classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OperationsPanel           matlab.ui.container.Panel
        DownloadimgButton         matlab.ui.control.Button
        GenerateimgButton         matlab.ui.control.Button
        ColumnSpinner             matlab.ui.control.Spinner
        ColumnSpinnerLabel        matlab.ui.control.Label
        RowSpinner                matlab.ui.control.Spinner
        RowSpinnerLabel           matlab.ui.control.Label
        DeleteimgButton           matlab.ui.control.Button
        UpdateimgButton           matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        PicturesListListBox       matlab.ui.control.ListBox
        PicturesListListBoxLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgs = struct('img', {}, 'path', {}, 'name', {});
    end
    
    methods (Access = private)
         function updateImgList(app)
            app.PicturesListListBox.Items = {app.imgs.name};
         end
        
        function displaySelectImage(app)
            selectedName = app.PicturesListListBox.Value;
            if ~isempty(selectedName)
                % 查找对应的图片数据
                imgIndex = find(strcmp({app.imgs.name}, selectedName));
                if ~isempty(imgIndex)
                    img = app.imgs(imgIndex).img;
                    % 在ImageAxes中显示图片
                    imshow(img, 'Parent', app.UIAxes);
                end
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateimgButton
        function updateImg(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif', '图片文件 (*.jpg, *.jpeg, *.png, *.gif)'}, '选择图片');
            % 如果用户选择了图片文件
            if ischar(filename)
                img = imread(fullfile(pathname, filename));
                imgName = filename; % 使用文件名作为图片名称
                app.imgs(end + 1).img = img;
                app.imgs(end).path = fullfile(pathname, filename);
                app.imgs(end).name = imgName;
                app.updateImgList();
            else
                % 如果用户取消了选择，则显示提示信息
                msgbox('未选择图片文件。', '警告', 'warn');
            end
        end

        % Value changed function: PicturesListListBox
        function PicturesListListBoxValueChanged(app, event)
            app.displaySelectImage();
        end

        % Button pushed function: DeleteimgButton
        function deleteImage(app, event)
            selectedName = app.PicturesListListBox.Value;
            if ~isempty(selectedName)
                % 查找对应的图片数据
                imgIndex = find(strcmp({app.imgs.name}, selectedName));
                if ~isempty(imgIndex)
                    app.imgs(imgIndex) = [];
                    % 更新图片列表
                    app.updateImgList();
                    if isempty(app.PicturesListListBox.Items)
                        return;
                    end
                    app.PicturesListListBox.Value = app.PicturesListListBox.Items(end);
                    app.displaySelectImage();
                end
            else
                msgbox('未选择图片。', '警告', 'warn');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Img Show')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PicturesListListBoxLabel = uilabel(app.Panel);
            app.PicturesListListBoxLabel.Text = 'Pictures List';
            app.PicturesListListBox = uilistbox(app.Panel);
            app.PicturesListListBox.Items = {''};
            app.PicturesListListBox.ValueChangedFcn = createCallbackFcn(app, @PicturesListListBoxValueChanged, true);
            app.PicturesListListBox.Value = {};
            app.OperationsPanel = uipanel(app.UIFigure);
            app.OperationsPanel.Title = 'Operations';
            app.UpdateimgButton = uibutton(app.OperationsPanel, 'push');
            app.UpdateimgButton.ButtonPushedFcn = createCallbackFcn(app, @updateImg, true);
            app.UpdateimgButton.Text = 'Update img';
            app.DeleteimgButton = uibutton(app.OperationsPanel, 'push');
            app.DeleteimgButton.ButtonPushedFcn = createCallbackFcn(app, @deleteImage, true);
            app.DeleteimgButton.Text = 'Delete img';
            app.RowSpinnerLabel = uilabel(app.OperationsPanel);
            app.RowSpinnerLabel.Text = 'Row';
            app.RowSpinner = uispinner(app.OperationsPanel);
            app.RowSpinner.Limits = [1 Inf];
            app.RowSpinner.Value = 1;
            app.ColumnSpinnerLabel = uilabel(app.OperationsPanel);
            app.ColumnSpinnerLabel.Text = 'Column';
            app.ColumnSpinner = uispinner(app.OperationsPanel);
            app.ColumnSpinner.Limits = [1 Inf];
            app.ColumnSpinner.Value = 1;
            app.GenerateimgButton = uibutton(app.OperationsPanel, 'push');
            app.GenerateimgButton.Text = 'Generate img';
            app.DownloadimgButton = uibutton(app.OperationsPanel, 'push');
            app.DownloadimgButton.Text = 'Download img';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end